classdef frf_generator < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        ExportButton             matlab.ui.control.Button
        SelectAxisDropDown       matlab.ui.control.DropDown
        SelectAxisDropDownLabel  matlab.ui.control.Label
        NofileLabel              matlab.ui.control.Label
        CalculateButton          matlab.ui.control.Button
        LoadfileButton           matlab.ui.control.Button
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = public)
        X double % Vibration signal
        currentAxis double
        fileName string = "No file selected";
        xAxis double
        yAxis double
        zAxis double
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(comp, event)
            [file,location] = uigetfile({'*.csv'},...
               'Select a CSV file');

            comp.NofileLabel.Text = file;
            
            datatable = readtable(fullfile(location, file));
            % Convert the table to an array
            data= table2array(datatable);
            
            comp.xAxis = data(:, 1);
            comp.yAxis = data(:, 2);
            comp.zAxis = data(:, 3);
        end

        % Value changed function: SelectAxisDropDown
        function SelectAxisDropDownValueChanged(comp, event)
            value = comp.SelectAxisDropDown.Value;
            if value == "x-Axis"
                comp.X = comp.xAxis;
            elseif value == "y-Axis"
                comp.X = comp.yAxis;
            else
                comp.X = comp.zAxis;
            end
            % Plot
            % Remove DC offset
            comp.X = comp.X-mean(comp.X);
            
            % Sampling rates
            fs = 168;
            T = 1/fs;
            L = length(comp.X);
            t = (0:L-1)*T;

            plot(comp.UIAxes, 1000*t, comp.X);
            title(comp.UIAxes, "Time domain Vibration Signal",'FontSize', 18)
            xlabel(comp.UIAxes, "t (milliseconds)")
            ylabel(comp.UIAxes, "X(t)")
            xlim(comp.UIAxes, [1, length(comp.X)]);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(comp, event)
            % Sampling rates
            fs = 168;
            L = length(comp.X);

            % Calculate FFT
            Y = fft(comp.X);
            
            % Take single sided amplitude spectrum
            % Scaling factor
            P2 = abs(Y/L);
            % Take the first half of the DFT
            P1 = P2(1:L/2+1);
            % Scaling by 2
            P1(2:end-1) = 2*P1(2:end-1);
            
            % Plot PSD using FFT
            f = fs/L*(0:(L/2));
            plot(comp.UIAxes_3, f, P1,"LineWidth",1) 
            title(comp.UIAxes_3, "Single-Sided Amplitude Spectrum of Vibration Signal", 'FontSize', 18)
            xlabel(comp.UIAxes_3, "f (Hz)")
            ylabel(comp.UIAxes_3, "|P(f)|")

            % Calculate STFT
            [p,f,t] = pspectrum(comp.X,fs,'spectrogram');
            
            waterfall(comp.UIAxes_2, f,t,p')
            xlabel(comp.UIAxes_2,'Frequency (Hz)')
            ylabel(comp.UIAxes_2, 'Time (seconds)')
            title(comp.UIAxes_2, "Spectrogram of the Vibration Signal",'FontSize', 18)
%             wtf = gca;
%             wtf.XDir = 'reverse';
%             view([30 45])
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 559 392];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            title(comp.UIAxes, 'Output Vibration Pattern')
            xlabel(comp.UIAxes, 'time (s)')
            ylabel(comp.UIAxes, 'Amplitude')
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes_3 = uiaxes(comp.GridLayout);
            title(comp.UIAxes_3, 'PSD')
            xlabel(comp.UIAxes_3, 'frequency (Hz)')
            ylabel(comp.UIAxes_3, '|P(f)|')
            zlabel(comp.UIAxes_3, 'Z')
            comp.UIAxes_2 = uiaxes(comp.GridLayout);
            title(comp.UIAxes_2, 'STFT')
            xlabel(comp.UIAxes_2, 'time (s)')
            ylabel(comp.UIAxes_2, '|P(f)|')
            zlabel(comp.UIAxes_2, 'Z')
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.LoadfileButton = uibutton(comp.GridLayout2, 'push');
            comp.LoadfileButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LoadfileButtonPushed, true);
            comp.LoadfileButton.Text = 'Load file';
            comp.CalculateButton = uibutton(comp.GridLayout2, 'push');
            comp.CalculateButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @CalculateButtonPushed, true);
            comp.CalculateButton.Text = 'Calculate';
            comp.NofileLabel = uilabel(comp.GridLayout2);
            comp.NofileLabel.Text = 'No file';
            comp.SelectAxisDropDownLabel = uilabel(comp.GridLayout2);
            comp.SelectAxisDropDownLabel.Text = 'Select Axis';
            comp.SelectAxisDropDown = uidropdown(comp.GridLayout2);
            comp.SelectAxisDropDown.Items = {'x-Axis', 'y-Axis', 'z-Axis'};
            comp.SelectAxisDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SelectAxisDropDownValueChanged, true);
            comp.SelectAxisDropDown.Value = 'x-Axis';
            comp.ExportButton = uibutton(comp.GridLayout2, 'push');
            comp.ExportButton.Text = 'Export';
        end
    end
end